classdef OperatorViewer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        GridLayout                matlab.ui.container.GridLayout
        LeftPanel                 matlab.ui.container.Panel
        GridLayout2               matlab.ui.container.GridLayout
        IsolinesButton            matlab.ui.control.StateButton
        GradientButton            matlab.ui.control.StateButton
        ThetaSliderLabel          matlab.ui.control.Label
        ThetaSlider               matlab.ui.control.Slider
        ResolutionEditFieldLabel  matlab.ui.control.Label
        ResolutionEditField       matlab.ui.control.NumericEditField
        EditField                 matlab.ui.control.NumericEditField
        RightPanel                matlab.ui.container.Panel
        UIAxes                    matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        Grid(1,1)  % Description
        S   % surface
        L   % lines
        G   % Gradient
        F   % Function value
        dF1 % F1 gradient
        dF2 % F2 gradient
    end
    
    methods (Access = public)
        
        function set_grid(app, grid)
            app.Grid = grid;
            res   = app.ResolutionEditField.Value;
            theta = app.ThetaSlider.Value;
            app.update_operator(res, theta);
        end
    end
    
    methods (Access = private)
        
        function update_operator(app, res, theta)
            [f1,f2] = meshgrid(linspace(0,1,res+1));
            [app.F, app.dF1, app.dF2] = app.Grid.fetch(f1,f2,theta*ones(size(f1)));
            if isempty(app.S)
%                 app.S = surf(app.UIAxes, f1, f2, zeros(size(f1)), app.F, 'EdgeColor', 'none');
                app.S = surf(app.UIAxes, f1, f2, app.F, 'EdgeColor', 'none');
            else
%                 set(app.S, 'XData', f1, 'YData', f2, 'ZData', zeros(size(f1)), 'CData', app.F);
                set(app.S, 'XData', f1, 'YData', f2, 'ZData', app.F);
            end
            if app.IsolinesButton.Value
                if ~isempty(app.L)
                    delete(app.L);
                end
                [~,app.L] = contour3(app.UIAxes, f1, f2, app.F, linspace(0,1,11), 'LineColor','k');
            else
                if ~isempty(app.L)
                    delete(app.L);
                end
            end
            if app.GradientButton.Value
                set(app.S, 'XData', f1, 'YData', f2, 'ZData', app.dF1);
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, grid)
            hold(app.UIAxes,"on");
            app.set_grid(grid);
            view(app.UIAxes, 2);
            colormap(app.UIAxes,implicit_field_color(128));
            caxis(app.UIAxes,[0 1]);
        end

        % Value changed function: ResolutionEditField
        function ResolutionEditFieldValueChanged(app, event)
            res   = app.ResolutionEditField.Value;
            theta = app.ThetaSlider.Value;
            app.update_operator(res, theta);
        end

        % Value changing function: ThetaSlider
        function ThetaSliderValueChanging(app, event)
            res   = app.ResolutionEditField.Value;
            theta = event.Value;
            app.EditField.Value = theta;
            app.update_operator(res, theta);            
        end

        % Value changed function: IsolinesButton
        function IsolinesButtonValueChanged(app, event)
            res   = app.ResolutionEditField.Value;
            theta = app.ThetaSlider.Value;
            app.update_operator(res, theta);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {575, 575};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {218, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Callback function
        function ThetaSliderValueChanged(app, event)
            res   = app.ResolutionEditField.Value;
            theta = app.ThetaSlider.Value;
            app.EditField.Value = theta;
            app.update_operator(res, theta);            
        end

        % Value changed function: GradientButton
        function GradientButtonValueChanged(app, event)
            res   = app.ResolutionEditField.Value;
            theta = app.ThetaSlider.Value;
            app.update_operator(res, theta);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.LeftPanel.Title = 'Settings';
            app.GridLayout2 = uigridlayout(app.LeftPanel);
            app.IsolinesButton = uibutton(app.GridLayout2, 'state');
            app.IsolinesButton.ValueChangedFcn = createCallbackFcn(app, @IsolinesButtonValueChanged, true);
            app.IsolinesButton.Text = 'Isolines';
            app.GradientButton = uibutton(app.GridLayout2, 'state');
            app.GradientButton.ValueChangedFcn = createCallbackFcn(app, @GradientButtonValueChanged, true);
            app.GradientButton.Text = 'Gradient';
            app.ThetaSliderLabel = uilabel(app.GridLayout2);
            app.ThetaSliderLabel.Text = 'Theta';
            app.ThetaSlider = uislider(app.GridLayout2);
            app.ThetaSlider.Limits = [0 1];
            app.ThetaSlider.MajorTicks = [0 0.5 1];
            app.ThetaSlider.ValueChangingFcn = createCallbackFcn(app, @ThetaSliderValueChanging, true);
            app.ThetaSlider.MinorTicks = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.ResolutionEditFieldLabel = uilabel(app.GridLayout2);
            app.ResolutionEditFieldLabel.Text = 'Resolution';
            app.ResolutionEditField = uieditfield(app.GridLayout2, 'numeric');
            app.ResolutionEditField.ValueChangedFcn = createCallbackFcn(app, @ResolutionEditFieldValueChanged, true);
            app.ResolutionEditField.Value = 128;
            app.EditField = uieditfield(app.GridLayout2, 'numeric');
            app.EditField.Limits = [0 1];
            app.RightPanel = uipanel(app.GridLayout);
            app.RightPanel.Title = 'Operator';
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Field')
            xlabel(app.UIAxes, 'f1')
            ylabel(app.UIAxes, 'f2')
            app.UIAxes.XLim = [0 1];
            app.UIAxes.YLim = [0 1];
            app.UIAxes.XTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.UIAxes.YTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = OperatorViewer(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end