classdef OperatorViewer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        Ax                          matlab.ui.control.UIAxes
        ISliderLabel                matlab.ui.control.Label
        I                           matlab.ui.control.Slider
        alphaSliderLabel            matlab.ui.control.Label
        alpha                       matlab.ui.control.Slider
        SettingsPanel               matlab.ui.container.Panel
        NewButton                   matlab.ui.control.Button
        NameEditFieldLabel          matlab.ui.control.Label
        Name                        matlab.ui.control.EditField
        ResolutionEditFieldLabel    matlab.ui.control.Label
        Res                         matlab.ui.control.NumericEditField
        ScaleLabel                  matlab.ui.control.Label
        Scale                       matlab.ui.control.NumericEditField
        ResetCurrentButton          matlab.ui.control.Button
        ResetAllButton              matlab.ui.control.Button
        SaveButton                  matlab.ui.control.Button
        LoadButton                  matlab.ui.control.Button
        F_dLabel                    matlab.ui.control.Label
        Fd                          matlab.ui.control.Slider
        Preview                     matlab.ui.control.UIAxes
        InterpolationDropDownLabel  matlab.ui.control.Label
        Interpolation               matlab.ui.control.DropDown
        ResolutionEditFieldLabel_2  matlab.ui.control.Label
        Resolution                  matlab.ui.control.NumericEditField
        ExportButton                matlab.ui.control.Button
        ImportButton                matlab.ui.control.Button
    end


    properties (Access = public)
        Data % Operator data
    end
    
    methods (Access = private)
    
        function update(app)
            cla(app.Ax);
            cla(app.Preview);
            j = app.Fd.Value;
            i = app.I.Value;
            app.Data.show(app.Ax,j);
            n = app.Data.size();
            if( n(1) < 100 )
                hold(app.Ax,'on');
                scatter(app.Ax,app.Data.U(:,j),app.Data.V(:,j),10,'k','filled');
            end
            hold(app.Ax,'on');
            scatter(app.Ax,app.Data.U(i,j),app.Data.V(i,j),20,'red','filled');
            app.Data.preview(app.Preview,256,app.Interpolation.Value);
        end
        
        function enable(app)
            app.alpha.Enable              = 'on';
            app.Interpolation.Enable      = 'on';
            
            n = app.Data.size();
            
            app.I.Enable                  = 'on';
            app.I.Limits = [1 n(1)];
            app.I.Value = 1;
            if( n(1) < 100 )
                app.I.MajorTicks = [1:n(1)];
            end
            if( n(1) < 50 )
                app.I.MinorTicks = [1:n(1)];
            end
            
            
            app.Fd.Enable                 = 'on';
            app.Fd.Limits = [1 n(2)];
            app.Fd.Value = 1;
            if( n(2) < 100 )
                app.Fd.MajorTicks = [1:n(2)];
            end
            if( n(2) < 50 )
                app.Fd.MinorTicks = [1:n(2)];
            end
            
            app.ResetCurrentButton.Enable = 'on';
            app.ResetAllButton.Enable     = 'on';
            app.SaveButton.Enable         = 'on';
        end
        
        function select_I(app,event)
            p = event.IntersectionPoint;
            app.I.Value = round(p(2));
            app.update();
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Data = Op();            
        end

        % Button pushed function: NewButton
        function NewButtonPushed(app, event)
            app.Data = Op(app.Res.Value,app.Scale.Value,app.Name.Value);
            
            app.enable();
            app.update();
        end

        % Value changed function: alpha
        function alphaValueChanged(app, event)
            value = app.alpha.Value;
            j     = app.Fd.Value;
            i     = app.I.Value;
            app.Data.V(i,j) = value;
            app.update();
        end

        % Value changed function: Fd
        function FdValueChanged(app, event)
            value = round(app.Fd.Value);
            app.Fd.Value = value;
            j     = value;
            i     = app.I.Value;
            app.alpha.Value = app.Data.V(i,j);
            app.update();
        end

        % Value changed function: I
        function IValueChanged(app, event)
            value = round(app.I.Value);
            app.I.Value = value;
            j     = app.Fd.Value;
            i     = value;
            app.alpha.Value = app.Data.V(i,j);
            app.update();
        end

        % Button pushed function: ResetCurrentButton
        function ResetCurrentButtonPushed(app, event)
            j     = app.Fd.Value;
            if( j == 1 )
                V = repmat(0.5,size(app.Data.V,2),1);
            else
                V = app.Data.V(:,j-1);
            end
            app.Data.V(:,j) = V;
            app.alpha.Value = V(app.I.Value);
            app.update();
        end

        % Button pushed function: ResetAllButton
        function ResetAllButtonPushed(app, event)
            app.Data.V = repmat(0.5,size(app.Data.V));
            app.alpha.Value = 0.5;
            app.update();
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            [file,path] = uiputfile('*.op');
            if(~isequal(file,0))
                app.Data.store(fullfile(path,file));
            end
        end

        % Value changed function: Interpolation
        function InterpolationValueChanged(app, event)
            value = app.Interpolation.Value;
            app.update();
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            [file,path] = uigetfile('*.op');
            if(~isequal(file,0))
                filename = fullfile(path,file);
                app.Data = Op();
                app.Data.load(filename);
                app.enable();
                n = app.Data.size();
                app.Res.Value = n(1);
                app.Scale.Value = app.Data.Scale;
                app.Name.Value = app.Data.Name;
                app.update();
            end
        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)
            r = app.Resolution.Value;
            Img = app.Data.op2img([r,r/2],app.Interpolation.Value);
            Img = [Img;repmat(0.5,size(Img))];
            [file,path] = uiputfile('*.png');
            imwrite(Img,fullfile(path,file),'png');
        end

        % Button pushed function: ImportButton
        function ImportButtonPushed(app, event)
            [file,path] = uigetfile('*.png');
            if(~isequal(file,0))
                filename = fullfile(path,file);
                app.Data.fromHeightFieldImage(filename);
                app.enable();
                n = app.Data.size();
                app.Res.Value = n(1);
                app.Scale.Value = app.Data.Scale;
                app.Name.Value = app.Data.Name;
                app.update();
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            setAutoResize(app, app.UIFigure, true)
            app.Ax = uiaxes(app.UIFigure);
            app.Ax.XLim = [0 1];
            app.Ax.XLimMode = 'manual';
            app.Ax.YLim = [0 1];
            app.Ax.YLimMode = 'manual';
            app.ISliderLabel = uilabel(app.UIFigure);
            app.ISliderLabel.Text = 'I';
            app.I = uislider(app.UIFigure);
            app.I.Limits = [0 1];
            app.I.MajorTickLabels = {'', '', '', '', '', '', '', '', '', '', ''};
            app.I.ValueChangedFcn = createCallbackFcn(app, @IValueChanged, true);
            app.I.Enable = 'off';
            app.alphaSliderLabel = uilabel(app.UIFigure);
            app.alphaSliderLabel.Text = 'alpha';
            app.alpha = uislider(app.UIFigure);
            app.alpha.Limits = [0 1];
            app.alpha.MajorTicks = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.alpha.MajorTickLabels = {'', '', '', '', '', '', '', '', '', '', ''};
            app.alpha.ValueChangedFcn = createCallbackFcn(app, @alphaValueChanged, true);
            app.alpha.Enable = 'off';
            app.alpha.Value = 0.5;
            app.SettingsPanel = uipanel(app.UIFigure);
            app.SettingsPanel.Title = 'Settings';
            app.NewButton = uibutton(app.SettingsPanel, 'push');
            app.NewButton.ButtonPushedFcn = createCallbackFcn(app, @NewButtonPushed, true);
            app.NewButton.Text = 'New';
            app.NameEditFieldLabel = uilabel(app.SettingsPanel);
            app.NameEditFieldLabel.Text = 'Name';
            app.Name = uieditfield(app.SettingsPanel, 'text');
            app.ResolutionEditFieldLabel = uilabel(app.SettingsPanel);
            app.ResolutionEditFieldLabel.Text = 'Resolution';
            app.Res = uieditfield(app.SettingsPanel, 'numeric');
            app.Res.Limits = [3 1024];
            app.Res.ValueDisplayFormat = '%.0f';
            app.Res.Value = 11;
            app.ScaleLabel = uilabel(app.SettingsPanel);
            app.ScaleLabel.Text = 'Scale';
            app.Scale = uieditfield(app.SettingsPanel, 'numeric');
            app.Scale.Limits = [0 Inf];
            app.Scale.ValueDisplayFormat = '%.4f';
            app.Scale.Value = 1;
            app.ResetCurrentButton = uibutton(app.SettingsPanel, 'push');
            app.ResetCurrentButton.ButtonPushedFcn = createCallbackFcn(app, @ResetCurrentButtonPushed, true);
            app.ResetCurrentButton.Enable = 'off';
            app.ResetCurrentButton.Text = 'Reset Current';
            app.ResetAllButton = uibutton(app.SettingsPanel, 'push');
            app.ResetAllButton.ButtonPushedFcn = createCallbackFcn(app, @ResetAllButtonPushed, true);
            app.ResetAllButton.Enable = 'off';
            app.ResetAllButton.Text = 'Reset All';
            app.SaveButton = uibutton(app.SettingsPanel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Enable = 'off';
            app.SaveButton.Text = 'Save...';
            app.LoadButton = uibutton(app.SettingsPanel, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load...';
            app.F_dLabel = uilabel(app.UIFigure);
            app.F_dLabel.Text = 'F_d';
            app.Fd = uislider(app.UIFigure);
            app.Fd.Limits = [0 1];
            app.Fd.MajorTicks = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.Fd.MajorTickLabels = {'', '', '', '', '', '', '', '', '', '', ''};
            app.Fd.ValueChangedFcn = createCallbackFcn(app, @FdValueChanged, true);
            app.Fd.Enable = 'off';
            app.Preview = uiaxes(app.UIFigure);
            title(app.Preview, 'Preview');
            app.Preview.XLim = [1 256];
            app.Preview.XLimMode = 'manual';
            app.Preview.YLim = [1 256];
            app.Preview.YLimMode = 'manual';
            app.Preview.XTick = [];
            app.Preview.XTickLabel = '';
            app.Preview.YTick = [];
            app.Preview.YTickLabel = '';
            app.InterpolationDropDownLabel = uilabel(app.UIFigure);
            app.InterpolationDropDownLabel.Text = 'Interpolation';
            app.Interpolation = uidropdown(app.UIFigure);
            app.Interpolation.Items = {'linear', 'nearest', 'cubic', 'spline'};
            app.Interpolation.ValueChangedFcn = createCallbackFcn(app, @InterpolationValueChanged, true);
            app.Interpolation.Enable = 'off';
            app.Interpolation.Value = 'linear';
            app.ResolutionEditFieldLabel_2 = uilabel(app.UIFigure);
            app.ResolutionEditFieldLabel_2.Text = 'Resolution';
            app.Resolution = uieditfield(app.UIFigure, 'numeric');
            app.Resolution.Limits = [64 1024];
            app.Resolution.Value = 512;
            app.ExportButton = uibutton(app.UIFigure, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Text = 'Export...';
            app.ImportButton = uibutton(app.UIFigure, 'push');
            app.ImportButton.ButtonPushedFcn = createCallbackFcn(app, @ImportButtonPushed, true);
            app.ImportButton.Text = 'Import...';
        end
    end

    methods (Access = public)

        % Construct app
        function app = OperatorViewer()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end