classdef AbstractBrushUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        DistanceFcnMenu        matlab.ui.container.Menu
        DistanceEuclideanMenu  matlab.ui.container.Menu
        CutOffFcnMenu          matlab.ui.container.Menu
        CutOffFixedMenu        matlab.ui.container.Menu
        CutOffLinearMenu       matlab.ui.container.Menu
        CutOffQuadraticMenu    matlab.ui.container.Menu
        CutOffCubicMenu        matlab.ui.container.Menu
        CutOffGaussMenu        matlab.ui.container.Menu
        CutOffWyvillMenu       matlab.ui.container.Menu
        PaintFcnMenu           matlab.ui.container.Menu
        PaintAddMenu           matlab.ui.container.Menu
        PaintSubtractMenu      matlab.ui.container.Menu
        PaintMultiplyMenu      matlab.ui.container.Menu
        PaintDivideMenu        matlab.ui.container.Menu
        PaintMixMenu           matlab.ui.container.Menu
        ValidateFcnMenu        matlab.ui.container.Menu
        ValidateIdentityMenu   matlab.ui.container.Menu
        BrushFunctionsPanel    matlab.ui.container.Panel
        DistanceFcnLabel       matlab.ui.control.Label
        CutOffFcnLabel         matlab.ui.control.Label
        PaintFcnLabel          matlab.ui.control.Label
        ValidateFcnLabel       matlab.ui.control.Label
        CutOffFcn              matlab.ui.control.EditField
        DistanceFcn            matlab.ui.control.EditField
        PaintFcn               matlab.ui.control.EditField
        ValidateFcn            matlab.ui.control.EditField
        ValidateFcnSignature   matlab.ui.control.Label
        PaintFcnSignature      matlab.ui.control.Label
        CutOffFcnSignature     matlab.ui.control.Label
        DistanceFcnSignature   matlab.ui.control.Label
        BrushDataPanel         matlab.ui.container.Panel
    end

    methods (Access = private)

        % Menu selected function: DistanceEuclideanMenu
        function DistanceEuclideanMenuSelected(app, event)
            app.DistanceFcn.Value = 'vecnorm(V,2,2)';
        end

        % Menu selected function: CutOffFixedMenu
        function CutOffFixedMenuSelected(app, event)
            app.CutOffFcn.Value = 'D<0';
        end

        % Menu selected function: CutOffLinearMenu
        function CutOffLinearMenuSelected(app, event)
            app.CutOffFcn.Value = 'D';
        end

        % Menu selected function: CutOffQuadraticMenu
        function CutOffQuadraticMenuSelected(app, event)
            app.CutOffFcn.Value = 'D.^2';
        end

        % Menu selected function: CutOffCubicMenu
        function CutOffCubicMenuSelected(app, event)
            app.CutOffFcn.Value = 'D.^3';
        end

        % Menu selected function: CutOffGaussMenu
        function CutOffGaussMenuSelected(app, event)
            app.CutOffFcn.Value = 'normalize(gaussmf(Distance,[0.3333 1]),0,1)';
        end

        % Menu selected function: CutOffWyvillMenu
        function CutOffWyvillMenuSelected(app, event)
            app.CutOffFcn.Value = 'wyvill(D)';
        end

        % Menu selected function: PaintAddMenu
        function PaintAddMenuSelected(app, event)
            app.PaintFcn.Value = 'P+B';
        end

        % Menu selected function: PaintSubtractMenu
        function PaintSubtractMenuSelected(app, event)
            app.PaintFcn.Value = 'P-B';
        end

        % Menu selected function: PaintMultiplyMenu
        function PaintMultiplyMenuSelected(app, event)
            app.PaintFcn.Value = 'P.*B';
        end

        % Menu selected function: PaintDivideMenu
        function PaintDivideMenuSelected(app, event)
            app.PaintFcn.Value = 'P./B';
        end

        % Menu selected function: PaintMixMenu
        function PaintMixMenuSelected(app, event)
            app.PaintFcn.Value = 'B';
        end

        % Menu selected function: ValidateIdentityMenu
        function ValidateIdentityMenuSelected(app, event)
            app.ValidateFcn.Value = 'V';
        end

        % Value changed function: CutOffFcn
        function CutOffFcnValueChanged(app, event)
            value = str2func(strcat(app.CutOffFcnLabel.Value,app.CutOffFcn.Value));
            
        end

        % Value changed function: DistanceFcn
        function DistanceFcnValueChanged(app, event)
            value = str2func(strcat(app.DistanceFcnLabel.Value,app.DistanceFcn.Value));
            
        end

        % Value changed function: PaintFcn
        function PaintFcnValueChanged(app, event)
            value = str2func(strcat(app.PaintFcnLabel.Value,app.PaintFcn.Value));
            
        end

        % Value changed function: ValidateFcn
        function ValidateFcnValueChanged(app, event)
            value = str2func(strcat(app.ValidateFcnLabel.Value,app.ValidateFcn.Value));
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.DistanceFcnMenu = uimenu(app.UIFigure);
            app.DistanceFcnMenu.Text = 'DistanceFcn';
            app.DistanceEuclideanMenu = uimenu(app.DistanceFcnMenu);
            app.DistanceEuclideanMenu.MenuSelectedFcn = createCallbackFcn(app, @DistanceEuclideanMenuSelected, true);
            app.DistanceEuclideanMenu.Text = 'Euclidean';
            app.CutOffFcnMenu = uimenu(app.UIFigure);
            app.CutOffFcnMenu.Text = 'CutOffFcn';
            app.CutOffFixedMenu = uimenu(app.CutOffFcnMenu);
            app.CutOffFixedMenu.MenuSelectedFcn = createCallbackFcn(app, @CutOffFixedMenuSelected, true);
            app.CutOffFixedMenu.Text = 'Fixed';
            app.CutOffLinearMenu = uimenu(app.CutOffFcnMenu);
            app.CutOffLinearMenu.MenuSelectedFcn = createCallbackFcn(app, @CutOffLinearMenuSelected, true);
            app.CutOffLinearMenu.Text = 'Linear';
            app.CutOffQuadraticMenu = uimenu(app.CutOffFcnMenu);
            app.CutOffQuadraticMenu.MenuSelectedFcn = createCallbackFcn(app, @CutOffQuadraticMenuSelected, true);
            app.CutOffQuadraticMenu.Text = 'Quadratic';
            app.CutOffCubicMenu = uimenu(app.CutOffFcnMenu);
            app.CutOffCubicMenu.MenuSelectedFcn = createCallbackFcn(app, @CutOffCubicMenuSelected, true);
            app.CutOffCubicMenu.Text = 'Cubic';
            app.CutOffGaussMenu = uimenu(app.CutOffFcnMenu);
            app.CutOffGaussMenu.MenuSelectedFcn = createCallbackFcn(app, @CutOffGaussMenuSelected, true);
            app.CutOffGaussMenu.Text = 'Gauss';
            app.CutOffWyvillMenu = uimenu(app.CutOffFcnMenu);
            app.CutOffWyvillMenu.MenuSelectedFcn = createCallbackFcn(app, @CutOffWyvillMenuSelected, true);
            app.CutOffWyvillMenu.Text = 'Wyvill';
            app.PaintFcnMenu = uimenu(app.UIFigure);
            app.PaintFcnMenu.Text = 'PaintFcn';
            app.PaintAddMenu = uimenu(app.PaintFcnMenu);
            app.PaintAddMenu.MenuSelectedFcn = createCallbackFcn(app, @PaintAddMenuSelected, true);
            app.PaintAddMenu.Text = 'Add';
            app.PaintSubtractMenu = uimenu(app.PaintFcnMenu);
            app.PaintSubtractMenu.MenuSelectedFcn = createCallbackFcn(app, @PaintSubtractMenuSelected, true);
            app.PaintSubtractMenu.Text = 'Subtract';
            app.PaintMultiplyMenu = uimenu(app.PaintFcnMenu);
            app.PaintMultiplyMenu.MenuSelectedFcn = createCallbackFcn(app, @PaintMultiplyMenuSelected, true);
            app.PaintMultiplyMenu.Text = 'Multiply';
            app.PaintDivideMenu = uimenu(app.PaintFcnMenu);
            app.PaintDivideMenu.MenuSelectedFcn = createCallbackFcn(app, @PaintDivideMenuSelected, true);
            app.PaintDivideMenu.Text = 'Divide';
            app.PaintMixMenu = uimenu(app.PaintFcnMenu);
            app.PaintMixMenu.MenuSelectedFcn = createCallbackFcn(app, @PaintMixMenuSelected, true);
            app.PaintMixMenu.Text = 'Mix';
            app.ValidateFcnMenu = uimenu(app.UIFigure);
            app.ValidateFcnMenu.Text = 'ValidateFcn';
            app.ValidateIdentityMenu = uimenu(app.ValidateFcnMenu);
            app.ValidateIdentityMenu.MenuSelectedFcn = createCallbackFcn(app, @ValidateIdentityMenuSelected, true);
            app.ValidateIdentityMenu.Text = 'Identity';
            app.BrushFunctionsPanel = uipanel(app.UIFigure);
            app.BrushFunctionsPanel.Title = 'Brush Functions';
            app.DistanceFcnLabel = uilabel(app.BrushFunctionsPanel);
            app.DistanceFcnLabel.Text = 'DistanceFcn';
            app.CutOffFcnLabel = uilabel(app.BrushFunctionsPanel);
            app.CutOffFcnLabel.Text = 'CutOffFcn';
            app.PaintFcnLabel = uilabel(app.BrushFunctionsPanel);
            app.PaintFcnLabel.Text = 'PaintFcn';
            app.ValidateFcnLabel = uilabel(app.BrushFunctionsPanel);
            app.ValidateFcnLabel.Text = 'ValidateFcn';
            app.CutOffFcn = uieditfield(app.BrushFunctionsPanel, 'text');
            app.CutOffFcn.ValueChangedFcn = createCallbackFcn(app, @CutOffFcnValueChanged, true);
            app.CutOffFcn.Value = 'D';
            app.DistanceFcn = uieditfield(app.BrushFunctionsPanel, 'text');
            app.DistanceFcn.ValueChangedFcn = createCallbackFcn(app, @DistanceFcnValueChanged, true);
            app.DistanceFcn.Value = 'vecnorm(V,2,2)';
            app.PaintFcn = uieditfield(app.BrushFunctionsPanel, 'text');
            app.PaintFcn.ValueChangedFcn = createCallbackFcn(app, @PaintFcnValueChanged, true);
            app.PaintFcn.Value = 'B';
            app.ValidateFcn = uieditfield(app.BrushFunctionsPanel, 'text');
            app.ValidateFcn.ValueChangedFcn = createCallbackFcn(app, @ValidateFcnValueChanged, true);
            app.ValidateFcn.Value = 'V';
            app.ValidateFcnSignature = uilabel(app.BrushFunctionsPanel);
            app.ValidateFcnSignature.Text = '@(V)';
            app.PaintFcnSignature = uilabel(app.BrushFunctionsPanel);
            app.PaintFcnSignature.Text = '@(P,B)';
            app.CutOffFcnSignature = uilabel(app.BrushFunctionsPanel);
            app.CutOffFcnSignature.Text = '@(D)';
            app.DistanceFcnSignature = uilabel(app.BrushFunctionsPanel);
            app.DistanceFcnSignature.Text = '@(V)';
            app.BrushDataPanel = uipanel(app.UIFigure);
            app.BrushDataPanel.Title = 'Brush Data';
        end
    end

    methods (Access = public)

        % Construct app
        function app = AbstractBrushUI

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end